classdef appfinal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        UploadthephotoButton          matlab.ui.control.Button
        ThresholdsliderSliderLabel    matlab.ui.control.Label
        ThresholdsliderSlider         matlab.ui.control.Slider
        ThresholdValueEditFieldLabel  matlab.ui.control.Label
        ThresholdValueEditField       matlab.ui.control.NumericEditField
        UIAxes                        matlab.ui.control.UIAxes
        UIAxes2                       matlab.ui.control.UIAxes
        UIAxes3                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Igray % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UploadthephotoButton
        function UploadthephotoButtonPushed(app, event)
            
            
            [filename, pathname]=uigetfile({'*.jpg;*.tiff'},"open file");
            fullpathname=[pathname, filename];
            imagefile=imread(fullpathname);
            imshow(imagefile,'parent',app.UIAxes);
            app.Igray=rgb2gray(imagefile);
            [row col]= size(app.Igray);
            t=0;
            for i=1:row
    
                for j=1:col
        
                   if app.Igray(i,j)>t
                     new(i,j)=1;
                   else 
                     new(i,j)=0;
            
                   end
               
                end 
            end 
            
            imshow(new,'parent',app.UIAxes2)
            imshow(app.Igray,'parent',app.UIAxes3)


        end

        % Callback function: ThresholdValueEditField, 
        % ThresholdsliderSlider
        function ThresholdsliderSliderValueChanging(app, event)
            changingValue = event.Value;
            
            [r c]= size(app.Igray);
            t2=changingValue;
      
            for i1=1:r
    
                for j1=1:c
        
                   if app.Igray(i1,j1)>t2
                     new2(i1,j1)=1;
                   else 
                     new2(i1,j1)=0;
            
                   end
               
                end 
            end 
            
            imshow(new2,'parent',app.UIAxes2)
            app.ThresholdValueEditField.Value=t2;
            

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UploadthephotoButton = uibutton(app.UIFigure, 'push');
            app.UploadthephotoButton.ButtonPushedFcn = createCallbackFcn(app, @UploadthephotoButtonPushed, true);
            app.UploadthephotoButton.Text = 'Upload the photo';
            app.ThresholdsliderSliderLabel = uilabel(app.UIFigure);
            app.ThresholdsliderSliderLabel.Text = 'Threshold slider';
            app.ThresholdsliderSlider = uislider(app.UIFigure);
            app.ThresholdsliderSlider.Limits = [0 300];
            app.ThresholdsliderSlider.ValueChangingFcn = createCallbackFcn(app, @ThresholdsliderSliderValueChanging, true);
            app.ThresholdValueEditFieldLabel = uilabel(app.UIFigure);
            app.ThresholdValueEditFieldLabel.Text = 'Threshold Value';
            app.ThresholdValueEditField = uieditfield(app.UIFigure, 'numeric');
            app.ThresholdValueEditField.ValueChangedFcn = createCallbackFcn(app, @ThresholdsliderSliderValueChanging, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'RGB Image')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Binary Image')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Gray image')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appfinal

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end