classdef appHEqual < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        UploadphotoButton  matlab.ui.control.Button
        aSliderLabel       matlab.ui.control.Label
        aSlider            matlab.ui.control.Slider
        aEditFieldLabel    matlab.ui.control.Label
        aEditField         matlab.ui.control.EditField
        bSliderLabel       matlab.ui.control.Label
        bSlider            matlab.ui.control.Slider
        bEditFieldLabel    matlab.ui.control.Label
        bEditField         matlab.ui.control.NumericEditField
        cSliderLabel       matlab.ui.control.Label
        cSlider            matlab.ui.control.Slider
        cEditFieldLabel    matlab.ui.control.Label
        cEditField         matlab.ui.control.NumericEditField
        dSliderLabel       matlab.ui.control.Label
        dSlider            matlab.ui.control.Slider
        dEditFieldLabel    matlab.ui.control.Label
        dEditField         matlab.ui.control.NumericEditField
        UIAxes             matlab.ui.control.UIAxes
        UIAxes2            matlab.ui.control.UIAxes
        UIAxes3            matlab.ui.control.UIAxes
        UIAxes4            matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Igray % Description
        hist
        eq_hist
        e_hist
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UploadphotoButton
        function UploadphotoButtonPushed(app, event)
            
            
            [filename, pathname]=uigetfile({'*.jpg;*.png'},"open file");
            fullpathname=[pathname, filename];
            imagefile=imread(fullpathname);
            imshow(imagefile,'parent',app.UIAxes);
            app.Igray=rgb2gray(imagefile);
            [M,N]= size(app.Igray);
            
            for i=1:256
               app.hist(i)=0;
            end
            
            for x=1:M
                 for y=1:N
                      for k= 1:256
                        if app.Igray(x,y)==k
                          app.hist(k)=app.hist(k)+1;
                        end
                       end
                 end
            end

            for t=1:256
               app.eq_hist(t)=0;
            end      
            
            
            a=1;
            b=50;
            c=90;
            d=125;

            
            sigma_hists=zeros(1,256);
            sigma_hists(a+1)=app.hist(a+1);
            q=zeros(1,256);
            for i=a+2:b+1
              sigma_hists(i) = sigma_hists(i-1) + app.hist(i);
    
            end
            q(a+1)= (round(((d-c)/sigma_hists(b+1))*sigma_hists(a+1)+c))+1;

            app.eq_hist(q(a+1)) = app.hist(a+1); 

            for p=a+2:b+1
              q(p)= (round(((d-c)/sigma_hists(b+1))*sigma_hists(p)+c))+1;
              app.eq_hist(q(p))=app.eq_hist(q(p))+app.hist(p);
            end


           for x=1:M
              for y=1:N
                 if (a<=app.Igray(x,y)) && (app.Igray(x,y)<=b)
                    Igray_eqlevel(x,y)=q(app.Igray(x,y));
                 else
                    Igray_eqlevel(x,y)=app.Igray(x,y);
                 end
               end
           end
           
           app.e_hist=zeros(1,256);


           for x=1:M
                for y=1:N
                      for l= 1:256
                         if Igray_eqlevel(x,y)==l
                         app.e_hist(l)=app.e_hist(l)+1;
                         end
                      end
                 end
            end


            imshow((app.Igray),'parent',app.UIAxes)
            imshow((Igray_eqlevel),'parent',app.UIAxes2)
            stem(app.hist, 'Parent',app.UIAxes3)
            stem(app.e_hist, 'Parent', app.UIAxes4)


        end

        % Callback function: aEditField, aSlider
        function aSliderValueChanging(app, event)
            changingValue = event.Value;
            a=changingValue;
            b=50;
            c=90;
            d=125;
            
            sigma_hists=zeros(1,256);
            sigma_hists(a+1)=app.hist(a+1);
            q=zeros(1,256);
            
            for i=a+2:b+1
              sigma_hists(i) = sigma_hists(i-1) + app.hist(i);
    
            end
            
            q(a+1)= (round(((d-c)/sigma_hists(b+1))*sigma_hists(a+1)+c))+1;

            app.eq_hist(q(a+1)) = app.hist(a+1); 

            for p=a+2:b+1
              q(p)= (round(((d-c)/sigma_hists(b+1))*sigma_hists(p)+c))+1;
              app.eq_hist(q(p))=app.eq_hist(q(p))+app.hist(p);
            end


           for x=1:M
              for y=1:N
                 if (a<=app.Igray(x,y)) && (app.Igray(x,y)<=b)
                    Igray_eqlevel(x,y)=q(app.Igray(x,y));
                 else
                    Igray_eqlevel(x,y)=app.Igray(x,y);
                 end
               end
           end
           
           app.e_hist=zeros(1,256);


           for x=1:M
                for y=1:N
                      for l= 1:256
                         if Igray_eqlevel(x,y)==l
                         app.e_hist(l)=app.e_hist(l)+1;
                         end
                      end
                 end
            end


            imshow((app.Igray),'parent',app.UIAxes)
            imshow((Igray_eqlevel),'parent',app.UIAxes2)
            stem(app.hist, 'Parent',app.UIAxes3)
            stem(app.e_hist, 'Parent', app.UIAxes4)
            app.aEditField.Value=a;
        end

        % Callback function: bEditField, bSlider
        function bSliderValueChanging(app, event)
            changingValue = event.Value;
            b=changingValue;
            a=50;
            c=90;
            d=125;
            
            sigma_hists=zeros(1,256);
            sigma_hists(a+1)=app.hist(a+1);
            q=zeros(1,256);
            
            for i=a+2:b+1
              sigma_hists(i) = sigma_hists(i-1) + app.hist(i);
    
            end
            
            q(a+1)= (round(((d-c)/sigma_hists(b+1))*sigma_hists(a+1)+c))+1;

            app.eq_hist(q(a+1)) = app.hist(a+1); 

            for p=a+2:b+1
              q(p)= (round(((d-c)/sigma_hists(b+1))*sigma_hists(p)+c))+1;
              app.eq_hist(q(p))=app.eq_hist(q(p))+app.hist(p);
            end


           for x=1:M
              for y=1:N
                 if (a<=app.Igray(x,y)) && (app.Igray(x,y)<=b)
                    Igray_eqlevel(x,y)=q(app.Igray(x,y));
                 else
                    Igray_eqlevel(x,y)=app.Igray(x,y);
                 end
               end
           end
           
           app.e_hist=zeros(1,256);


           for x=1:M
                for y=1:N
                      for l= 1:256
                         if Igray_eqlevel(x,y)==l
                         app.e_hist(l)=app.e_hist(l)+1;
                         end
                      end
                 end
            end


            imshow((app.Igray),'parent',app.UIAxes)
            imshow((Igray_eqlevel),'parent',app.UIAxes2)
            stem(app.hist, 'Parent',app.UIAxes3)
            stem(app.e_hist, 'Parent', app.UIAxes4)
            app.bEditField.Value=b;
            
        end

        % Callback function: cEditField, cSlider
        function cSliderValueChanging(app, event)
            changingValue = event.Value;
            
            a= 25;
            b=50;
            c=changingValue;
            d=125;
            
            sigma_hists=zeros(1,256);
            sigma_hists(a+1)=app.hist(a+1);
            q=zeros(1,256);
            
            for i=a+2:b+1
              sigma_hists(i) = sigma_hists(i-1) + app.hist(i);
    
            end
            
            q(a+1)= (round(((d-c)/sigma_hists(b+1))*sigma_hists(a+1)+c))+1;

            app.eq_hist(q(a+1)) = app.hist(a+1); 

            for p=a+2:b+1
              q(p)= (round(((d-c)/sigma_hists(b+1))*sigma_hists(p)+c))+1;
              app.eq_hist(q(p))=app.eq_hist(q(p))+app.hist(p);
            end


           for x=1:M
              for y=1:N
                 if (a<=app.Igray(x,y)) && (app.Igray(x,y)<=b)
                    Igray_eqlevel(x,y)=q(app.Igray(x,y));
                 else
                    Igray_eqlevel(x,y)=app.Igray(x,y);
                 end
               end
           end
           
           app.e_hist=zeros(1,256);


           for x=1:M
                for y=1:N
                      for l= 1:256
                         if Igray_eqlevel(x,y)==l
                         app.e_hist(l)=app.e_hist(l)+1;
                         end
                      end
                 end
            end


            imshow((app.Igray),'parent',app.UIAxes)
            imshow((Igray_eqlevel),'parent',app.UIAxes2)
            stem(app.hist, 'Parent',app.UIAxes3)
            stem(app.e_hist, 'Parent', app.UIAxes4)
            app.cEditField.Value=c;
        end

        % Callback function: dEditField, dSlider
        function dSliderValueChanging(app, event)
            changingValue = event.Value;
            a= 25;
            b=50;
            c=90;
            d=changingValue;
            
            sigma_hists=zeros(1,256);
            sigma_hists(a+1)=app.hist(a+1);
            q=zeros(1,256);
            
            for i=a+2:b+1
              sigma_hists(i) = sigma_hists(i-1) + app.hist(i);
    
            end
            
            q(a+1)= (round(((d-c)/sigma_hists(b+1))*sigma_hists(a+1)+c))+1;

            app.eq_hist(q(a+1)) = app.hist(a+1); 

            for p=a+2:b+1
              q(p)= (round(((d-c)/sigma_hists(b+1))*sigma_hists(p)+c))+1;
              app.eq_hist(q(p))=app.eq_hist(q(p))+app.hist(p);
            end


           for x=1:M
              for y=1:N
                 if (a<=app.Igray(x,y)) && (app.Igray(x,y)<=b)
                    Igray_eqlevel(x,y)=q(app.Igray(x,y));
                 else
                    Igray_eqlevel(x,y)=app.Igray(x,y);
                 end
               end
           end
           
           app.e_hist=zeros(1,256);


           for x=1:M
                for y=1:N
                      for l= 1:256
                         if Igray_eqlevel(x,y)==l
                         app.e_hist(l)=app.e_hist(l)+1;
                         end
                      end
                 end
            end


            imshow((app.Igray),'parent',app.UIAxes)
            imshow((Igray_eqlevel),'parent',app.UIAxes2)
            stem(app.hist, 'Parent',app.UIAxes3)
            stem(app.e_hist, 'Parent', app.UIAxes4)
            app.dEditField.Value=d;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UploadphotoButton = uibutton(app.UIFigure, 'push');
            app.UploadphotoButton.ButtonPushedFcn = createCallbackFcn(app, @UploadphotoButtonPushed, true);
            app.UploadphotoButton.Text = 'Upload photo';
            app.aSliderLabel = uilabel(app.UIFigure);
            app.aSliderLabel.Text = 'a';
            app.aSlider = uislider(app.UIFigure);
            app.aSlider.ValueChangingFcn = createCallbackFcn(app, @aSliderValueChanging, true);
            app.aEditFieldLabel = uilabel(app.UIFigure);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.UIFigure, 'text');
            app.aEditField.ValueChangedFcn = createCallbackFcn(app, @aSliderValueChanging, true);
            app.bSliderLabel = uilabel(app.UIFigure);
            app.bSliderLabel.Text = 'b';
            app.bSlider = uislider(app.UIFigure);
            app.bSlider.ValueChangingFcn = createCallbackFcn(app, @bSliderValueChanging, true);
            app.bEditFieldLabel = uilabel(app.UIFigure);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.UIFigure, 'numeric');
            app.bEditField.ValueChangedFcn = createCallbackFcn(app, @bSliderValueChanging, true);
            app.cSliderLabel = uilabel(app.UIFigure);
            app.cSliderLabel.Text = 'c';
            app.cSlider = uislider(app.UIFigure);
            app.cSlider.ValueChangingFcn = createCallbackFcn(app, @cSliderValueChanging, true);
            app.cEditFieldLabel = uilabel(app.UIFigure);
            app.cEditFieldLabel.Text = 'c';
            app.cEditField = uieditfield(app.UIFigure, 'numeric');
            app.cEditField.ValueChangedFcn = createCallbackFcn(app, @cSliderValueChanging, true);
            app.dSliderLabel = uilabel(app.UIFigure);
            app.dSliderLabel.Text = 'd';
            app.dSlider = uislider(app.UIFigure);
            app.dSlider.ValueChangingFcn = createCallbackFcn(app, @dSliderValueChanging, true);
            app.dEditFieldLabel = uilabel(app.UIFigure);
            app.dEditFieldLabel.Text = 'd';
            app.dEditField = uieditfield(app.UIFigure, 'numeric');
            app.dEditField.ValueChangedFcn = createCallbackFcn(app, @dSliderValueChanging, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'gray Image')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Equalized image')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Histogram gray')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Histogram Equalized')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appHEqual

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end