classdef appheqlevelfinallllllllllllll < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        aSliderLabel     matlab.ui.control.Label
        aSlider          matlab.ui.control.Slider
        bSliderLabel     matlab.ui.control.Label
        bSlider          matlab.ui.control.Slider
        cSliderLabel     matlab.ui.control.Label
        cSlider          matlab.ui.control.Slider
        dSliderLabel     matlab.ui.control.Label
        dSlider          matlab.ui.control.Slider
        aEditFieldLabel  matlab.ui.control.Label
        aEditField       matlab.ui.control.NumericEditField
        bEditFieldLabel  matlab.ui.control.Label
        bEditField       matlab.ui.control.NumericEditField
        cEditFieldLabel  matlab.ui.control.Label
        cEditField       matlab.ui.control.NumericEditField
        dEditFieldLabel  matlab.ui.control.Label
        dEditField       matlab.ui.control.NumericEditField
        uploadButton     matlab.ui.control.Button
        UIAxes           matlab.ui.control.UIAxes
        UIAxes2          matlab.ui.control.UIAxes
        UIAxes3          matlab.ui.control.UIAxes
        UIAxes4          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        a
        b
        c
        d
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changing function: aSlider
        function aSliderValueChanging(app, event)
            changingValue = event.Value;
            app.a=changingValue;
            app.aEditField.Value=round(app.a);
        end

        % Value changing function: bSlider
        function bSliderValueChanging(app, event)
            changingValue = event.Value;
            app.b=changingValue;
            app.bEditField.Value=round(app.b);
        end

        % Value changing function: cSlider
        function cSliderValueChanging(app, event)
            changingValue = event.Value;
            app.c=changingValue;
            app.cEditField.Value=round(app.c);
        end

        % Value changing function: dSlider
        function dSliderValueChanging(app, event)
            changingValue = event.Value;
            app.d=changingValue;
            app.dEditField.Value=round(app.d);
        end

        % Button pushed function: uploadButton
        function uploadButtonPushed(app, event)
            [filename, pathname]=uigetfile({'*.jpg;*.tiff'},"open file");
            fullpathname=[pathname, filename];
            imagefile=imread(fullpathname);
            imshow(imagefile,'parent',app.UIAxes);
            Igray=rgb2gray(imagefile);
            [M,N]= size(Igray);
            
           A=round(app.a);
           B=round(app.b);
           C=round(app.c);
           D=round(app.d);
            
            
            for i=1:256
              hist(i)=0;
            end
            
            for x=1:M
                 for y=1:N
                      for k= 1:256
                        if Igray(x,y)==k
                          hist(k)=hist(k)+1;
                        end
                       end
                 end
            end

            for t=1:256
                eq_hist(t)=0;
            end      


            sigma_hists=zeros(1,256);
            sigma_hists(A+1)=hist(A+1);
            q=zeros(1,256);
            for i=A+2:B+1
              sigma_hists(i) = sigma_hists(i-1) + hist(i);
    
            end
            q(A+1)= (round(((D-C)/sigma_hists(B))*sigma_hists(A)+C))+1;

            eq_hist(q(A+1)) = hist(A+1); 

            for p=A+2:B+1
              q(p)= (round(((D-C)/sigma_hists(B+1))*sigma_hists(p)+C))+1;
              eq_hist(q(p))=eq_hist(q(p))+hist(p);
            end


           for x=1:M
              for y=1:N
                 if (A<=Igray(x,y)) && (Igray(x,y)<=B)
                    Igray_eqlevel(x,y)=q(Igray(x,y));
                 else
                    Igray_eqlevel(x,y)=Igray(x,y);
                 end
               end
           end
           
           e_hist=zeros(1,256);


           for x=1:M
                for y=1:N
                      for l= 1:256
                         if Igray_eqlevel(x,y)==l
                         e_hist(l)=e_hist(l)+1;
                         end
                      end
                 end
            end


            imshow((Igray),'parent',app.UIAxes)
            imshow((Igray_eqlevel),'parent',app.UIAxes2)
            stem(hist, 'Parent',app.UIAxes3)
            stem(e_hist, 'Parent', app.UIAxes4)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.aSliderLabel = uilabel(app.UIFigure);
            app.aSliderLabel.Text = 'a';
            app.aSlider = uislider(app.UIFigure);
            app.aSlider.Limits = [0 255];
            app.aSlider.ValueChangingFcn = createCallbackFcn(app, @aSliderValueChanging, true);
            app.bSliderLabel = uilabel(app.UIFigure);
            app.bSliderLabel.Text = 'b';
            app.bSlider = uislider(app.UIFigure);
            app.bSlider.Limits = [0 255];
            app.bSlider.ValueChangingFcn = createCallbackFcn(app, @bSliderValueChanging, true);
            app.cSliderLabel = uilabel(app.UIFigure);
            app.cSliderLabel.Text = 'c';
            app.cSlider = uislider(app.UIFigure);
            app.cSlider.Limits = [0 255];
            app.cSlider.ValueChangingFcn = createCallbackFcn(app, @cSliderValueChanging, true);
            app.dSliderLabel = uilabel(app.UIFigure);
            app.dSliderLabel.Text = 'd';
            app.dSlider = uislider(app.UIFigure);
            app.dSlider.Limits = [0 255];
            app.dSlider.ValueChangingFcn = createCallbackFcn(app, @dSliderValueChanging, true);
            app.aEditFieldLabel = uilabel(app.UIFigure);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.UIFigure, 'numeric');
            app.bEditFieldLabel = uilabel(app.UIFigure);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.UIFigure, 'numeric');
            app.cEditFieldLabel = uilabel(app.UIFigure);
            app.cEditFieldLabel.Text = 'c';
            app.cEditField = uieditfield(app.UIFigure, 'numeric');
            app.dEditFieldLabel = uilabel(app.UIFigure);
            app.dEditFieldLabel.Text = 'd';
            app.dEditField = uieditfield(app.UIFigure, 'numeric');
            app.uploadButton = uibutton(app.UIFigure, 'push');
            app.uploadButton.ButtonPushedFcn = createCallbackFcn(app, @uploadButtonPushed, true);
            app.uploadButton.Text = 'upload';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Gray Scale Image')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Equalized Image')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Histogram of grayscale Image')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Histogram of equalized Image')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appheqlevelfinallllllllllllll

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end