classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        SlidersigmaLabel       matlab.ui.control.Label
        Slidersigma            matlab.ui.control.Slider
        SliderKLabel           matlab.ui.control.Label
        SliderK                matlab.ui.control.Slider
        UploadButton           matlab.ui.control.Button
        ValueofEditFieldLabel  matlab.ui.control.Label
        ValueofEditField       matlab.ui.control.NumericEditField
        ValueofKLabel          matlab.ui.control.Label
        ValueofKEditField      matlab.ui.control.NumericEditField
        SigmaLabel             matlab.ui.control.Label
        UIAxes                 matlab.ui.control.UIAxes
        UIAxes2                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        a
       b % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changing function: Slidersigma
        function SlidersigmaValueChanging(app, event)
            changingValue = event.Value;
           
            app.a=changingValue;
            app.ValueofEditField.Value=(app.a);
        end

        % Value changing function: SliderK
        function SliderKValueChanging(app, event)

            changingValue = event.Value;
            app.b=changingValue;
            app.ValueofKEditField.Value=(app.b);
        end

        % Button pushed function: UploadButton
        function UploadButtonPushed(app, event)
            
            [filename, pathname]=uigetfile({'*.jpg;*.tiff'},"open file");
            fullpathname=[pathname, filename];
            Image=rgb2gray(imread(fullpathname));
            
            imshow(Image,'parent',app.UIAxes);  
            
            
            sigma=app.a;
            K=app.b;
            m=round((6*sigma)) +1;
            LOG=zeros(2*m+1,2*m+1);


            x=0;
            y=1;
                    
                   for X=-m:m
                      x=x+1; 
                      
                      for Y=-m:m
                              R=sqrt(X^2+Y^2);
                              LOG(x,y)= K * ((R^2-(2*sigma^2))/sigma^4)* exp(-(R^2)/(2*sigma^2));
                              x=x+1;
                      end
                      x=0;
                      y=y+1;
                   end
                  
            filteredImage= zeros(size(Image));


            filteredImage = conv2( LOG,double(Image));  %f'(x,y)
%            
            
            E=zeros(size(Image));
            [M ,N]= size(Image);
            
             E(1,1)=0;           
        
       
             E(1,N)=0;
       
       
             E(M,1)=0;


             E(M,N)=0; 
            
            
            for t=1:M
                for r=1:N
                    
                    if filteredImage(t,r)==0 || filteredImage(t,r)<0.09
                   
                        
                        %four edges zero
                      
                           
                           %first row without 1st and last terms
                         
                       if t==1 && (r>1 && r<N)
                           a1= filteredImage(t,r-1)*filteredImage(t,r+1);
                             if a1<0
                                   E(t,r)=1;
                                  
                             end
                       end
                                  
                       %first column without 1st and last element          
                       if (t>1 && t<M)  && (r==1)    
                            a2= filteredImage(t-1,r)*filteredImage(t+1,r);
                             if a2<0
                                   E(t,r)=1;
                             end    
                       end 
                        %last row without 1st and last terms
                         
                       if t==M && (r>1 && r<N)
                           a3= filteredImage(t,r-1)*filteredImage(t,r+1);
                             if a3<0
                                   E(t,r)=1;
                             end     
                       end
                       %last column without 1st and last element          
                       if (t>1 && t<M)  && (r==N)    
                            a4= filteredImage(t-1,r)*filteredImage(t+1,r);
                             if a4<0
                                   E(t,r)=1;
                             end     
                       end          
                       if (t>1 && t<M)  && (r>1 && r<N)   
                           b1=filteredImage(t-1,r)*filteredImage(t+1,r);%up bottom
                           b2=filteredImage(t,r-1)*filteredImage(t,r+1);% left right
                           b3=filteredImage(t-1,r-1)*filteredImage(t+1,r+1);%upper left/ bottom right
                           b4=filteredImage(t-1,r+1)*filteredImage(t+1,r-1);%upper right/ bottom left
                           
                           if b1<0 || b2<0 ||b3<0 ||b4<0 
                             
                                E(t,r)=1;
                   
                                
                           end    
                       end
            
                    end
            
                end
            end  

         imshow(E,'parent',app.UIAxes2);  


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SlidersigmaLabel = uilabel(app.UIFigure);
            app.SlidersigmaLabel.Text = 'Slider sigma';
            app.Slidersigma = uislider(app.UIFigure);
            app.Slidersigma.Limits = [0.1 5];
            app.Slidersigma.ValueChangingFcn = createCallbackFcn(app, @SlidersigmaValueChanging, true);
            app.Slidersigma.Value = 0.1;
            app.SliderKLabel = uilabel(app.UIFigure);
            app.SliderKLabel.Text = 'Slider K';
            app.SliderK = uislider(app.UIFigure);
            app.SliderK.Limits = [50 500];
            app.SliderK.ValueChangingFcn = createCallbackFcn(app, @SliderKValueChanging, true);
            app.SliderK.Value = 50;
            app.UploadButton = uibutton(app.UIFigure, 'push');
            app.UploadButton.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonPushed, true);
            app.UploadButton.Text = 'Upload';
            app.ValueofEditFieldLabel = uilabel(app.UIFigure);
            app.ValueofEditFieldLabel.Text = 'Valueof ';
            app.ValueofEditField = uieditfield(app.UIFigure, 'numeric');
            app.ValueofEditField.Limits = [0.1 5];
            app.ValueofEditField.Value = 0.1;
            app.ValueofKLabel = uilabel(app.UIFigure);
            app.ValueofKLabel.Text = 'ValueofK';
            app.ValueofKEditField = uieditfield(app.UIFigure, 'numeric');
            app.SigmaLabel = uilabel(app.UIFigure);
            app.SigmaLabel.Text = 'Sigma';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Image')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Edge Detection')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end